classdef Wireworld < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        WIREWORLDLabel       matlab.ui.control.Label
        GenerationEditField  matlab.ui.control.NumericEditField
        GenerationLabel      matlab.ui.control.Label
        StopButton           matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
        DropDown             matlab.ui.control.DropDown
        StartButton          matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hImg %image representation of M
        M %grid
        updateTimer
        m %nb rows of M
        n %nb cols of N
        oldM
        nbGenerations
        zero = [0 1 0;
                1 0 1;
                1 0 1;
                0 1 0];
        one = [0 1 0;
               1 1 0;
               0 1 0;
               0 1 0];
    end
    
    methods (Access = private)

        function DrawGridLines(app, rows, cols)
            cla(app.UIAxes);
            app.m = size(app.M,1);
            app.n = size(app.M,2);
            cmap = [0 0 0 ; 1 1 0 ; 0 0 1; 1 0 0];
            colormap(app.UIAxes,cmap);
            clim(app.UIAxes, [0 3]);

            app.hImg = imagesc(app.UIAxes, app.M);
            app.hImg.ButtonDownFcn = @(src, event) app.ClickCallback(src, event);
            hold(app.UIAxes, 'on');

            for k = 0:cols
                x = [k+0.5, k+0.5];
                y = [0.5, rows+0.5];
                plot(app.UIAxes, x, y, 'Color', [0.5 0.5 0.5], 'LineWidth', 0.7);
            end

            for k = 0:rows
                x = [0.5, cols+0.5];
                y = [k+0.5, k+0.5];
                plot(app.UIAxes, x, y, 'Color', [0.5 0.5 0.5], 'LineWidth', 0.7);
            end

            hold(app.UIAxes, 'off');

            grid(app.UIAxes, 'on');
            set(app.UIAxes, 'GridColor', 'k', 'GridAlpha', 1, 'LineWidth', 1);
            set(app.UIAxes, 'XTick', 0.5:1:(cols + 0.5), 'YTick', 0.5:1:(rows + 0.5));
            set(app.UIAxes, 'XTickLabel', [], 'YTickLabel', []);
            set(app.UIAxes, 'Color', 'none');
            set(app.UIAxes, 'XLim', [0.5, cols + 0.5], 'YLim', [0.5, rows + 0.5]);
            set(app.UIAxes, 'DataAspectRatio', [1 1 1], 'PlotBoxAspectRatio', [1 1 1]);
            set(app.UIAxes, 'ActivePositionProperty', 'position');
            app.UIAxes.Toolbar = [];
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
        end

        
        function sandbox(app)
            app.oldM = app.M;
            set(app.hImg, 'CData', app.M);
        end
        
        function diode(app)
            diode1 = [0 1 3 2 1 0 0 0 0 0 0 0 0 0 1 1 0 0 0 0 0 0 0 0 0 0 0;
                      1 0 0 0 0 1 1 1 1 1 1 1 1 1 1 0 1 1 1 1 1 1 1 1 1 1 1;
                      0 1 1 1 1 0 0 0 0 0 0 0 0 0 1 1 0 0 0 0 0 0 0 0 0 0 0];

            diode2 = [0 1 3 2 1 0 0 0 0 0 0 0 0 0 1 1 0 0 0 0 0 0 0 0 0 0 0;
                      1 0 0 0 0 1 1 1 1 1 1 1 1 1 0 1 1 1 1 1 1 1 1 1 1 1 1;
                      0 1 1 1 1 0 0 0 0 0 0 0 0 0 1 1 0 0 0 0 0 0 0 0 0 0 0];
            
            [j, k] = size(diode1);
            r = app.m/4;
            c = app.n/2 - k/2;
            app.M(r:r+j-1, c:c+k-1) = diode1;
            app.M(3*r:3*r+j-1, c:c+k-1) = diode2;
            app.oldM = app.M;
            set(app.hImg, 'CData', app.M);
        end
        
        function not(app)
            not1 = [0 0 0 0 0 0 0 0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                    1 1 1 1 1 1 1 1 0 0 1 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1;
                    0 0 0 0 0 0 0 0 0 2 2 2 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                    0 0 0 0 0 0 0 0 3 0 3 3 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                    0 0 0 0 0 0 0 0 1 2 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];

            not2 = [0 3 2 0 0 0 0 0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                    1 0 0 1 1 1 1 1 0 0 1 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1;
                    0 2 3 0 0 0 0 0 0 2 2 2 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                    0 0 0 0 0 0 0 0 3 0 3 3 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                    0 0 0 0 0 0 0 0 1 2 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];

            [j, k] = size(not1);
            r = app.m/4;
            c = app.n/2 - k/2;
            app.M(r:r+j-1, c:c+k-1) = not1;
            app.M(3*r:3*r+j-1, c:c+k-1) = not2;
            app.oldM = app.M;
            set(app.hImg, 'CData', app.M);
        end

        function clock(app)
            clock1 = [0 3 2 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                      1 0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1;
                      0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];

            clock2 = [0 1 3 2 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                      1 0 0 0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1;
                      0 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];

            clock3 = [0 1 1 3 2 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                      1 0 0 0 0 0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1;
                      0 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            
            [j, k] = size(clock1);
            r = app.m/6;
            c = app.n/2 - k/2;
            app.M(r:r+j-1, c:c+k-1) = clock1;
            app.M(3*r:3*r+j-1, c:c+k-1) = clock2;
            app.M(5*r:5*r+j-1, c:c+k-1) = clock3;
            app.oldM = app.M;
            set(app.hImg, 'CData', app.M);
        end

        function xor(app)
            xor = [0 1 3 2 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                   1 0 0 0 0 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                   0 1 1 1 1 0 0 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                   0 0 0 0 0 0 0 0 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                   0 0 0 0 0 0 0 0 1 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1;
                   0 0 0 0 0 0 0 0 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                   0 1 3 2 1 0 0 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                   1 0 0 0 0 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0; 
                   0 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];

            [j, k] = size(xor);
            r = app.m/2 - j/2;
            c = app.n/2 - k/2;
            app.M(r:r+j-1 , c:c+k-1) = xor;
            app.oldM = app.M;
            set(app.hImg, 'CData', app.M);
        end
        
        function or(app)
            or = [0 1 3 2 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                  1 0 0 0 0 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                  0 1 1 1 1 0 0 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                  0 0 0 0 0 0 0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1;
                  0 1 1 1 1 0 0 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                  1 0 0 0 0 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                  0 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];

            [j, k] = size(or);
            r = app.m/2 - j/2;
            c = app.n/2 - k/2;
            app.M(r:r+j-1 , c:c+k-1) = or;
            app.oldM = app.M;
            set(app.hImg, 'CData', app.M);
        end


        function and(app)
            and = [0 0 0 0 0 0 0 0 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                   0 1 3 2 1 0 0 0 0 1 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                   1 0 0 0 0 1 0 0 0 1 0 1 0 0 0 0 0 1 0 1 1 0 0 0 0 0 0 0 0 0 0;
                   0 1 1 1 1 0 1 1 1 0 0 1 0 0 0 0 1 1 1 0 0 1 0 0 0 0 0 0 0 0 0;
                   0 0 0 0 0 0 0 0 0 0 0 1 0 1 0 1 0 1 0 1 0 0 1 1 1 1 1 1 1 1 1;
                   0 1 3 2 1 0 1 1 1 1 0 0 1 1 1 0 0 0 0 1 0 0 0 0 0 0 0 0 0 0 0;
                   1 0 0 0 0 1 0 0 0 0 1 0 0 1 0 1 0 0 0 1 0 0 0 0 0 0 0 0 0 0 0;
                   0 1 1 1 1 0 0 0 0 0 0 1 0 0 0 0 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0;
                   0 0 0 0 0 0 0 0 0 0 0 0 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];

            [j, k] = size(and);
            r = app.m/2 - j/2;
            c = app.n/2 - k/2;
            app.M(r:r+j-1 , c:c+k-1) = and;
            
            app.oldM = app.M;
            set(app.hImg, 'CData', app.M);
        end

        function flipflop(app)
            flipflop = [1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                        1 0 1 0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 2 3;
                        1 1 0 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                        1 0 1 0 0 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                        1 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                        0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                        0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                        0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1;
                        0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                        1 1 1 0 0 0 0 0 0 0 0 0 0 0 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                        1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                        1 1 1 0 0 1 1 1 1 1 1 1 1 3 2 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                        0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;
                        1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];

            [j, k] = size(flipflop);
            r = app.m/2 - j/2;
            c = app.n/2 - k/2;
            app.M(r:r+j-1 , c:c+k-1) = flipflop;
            app.oldM = app.M;
            set(app.hImg, 'CData', app.M);
        
        end

        function binaryAdder(app)            
            for i = 2:6:44
                app.M(2:5 , i:i+2) = app.zero;
                app.M(15:18 , i:i+2) = app.zero;
            end

            for i = 75:6:117
                app.M(10:13 , i:i+2) = app.zero;
            end
            
            for i = 2:49
                app.M(7 , i) = 1;
                app.M(13 , i) = 1;
            end

            for i = 70:119
                app.M(8,i) = 1;
                if(mod(i-70,6) == 0 && i ~= 70)
                    app.M(6,i) = 1;
                end
            end

            B = [0 0 0 0 0 0 0 0 0 0 0 1 1 1 1 1 1 0 0 0;
                 0 0 0 0 0 0 0 0 0 0 1 0 0 0 0 0 0 1 0 0;
                 1 0 0 0 0 0 1 1 1 1 0 0 1 1 0 0 1 1 1 1;
                 0 1 0 0 0 1 0 0 0 0 0 1 0 0 3 0 1 0 0 1;
                 1 1 1 1 0 1 0 0 0 1 0 1 1 2 0 0 1 1 1 1;
                 1 0 0 1 1 0 1 1 1 1 1 0 0 0 0 0 0 1 0 0;
                 1 1 1 1 0 1 0 0 0 1 0 1 0 1 0 0 1 0 0 0;
                 0 1 0 0 0 1 0 0 0 0 0 3 0 0 0 1 0 0 0 0;
                 1 1 0 0 1 1 1 0 0 0 2 2 2 0 1 0 0 0 0 0;
                 0 0 1 0 0 1 0 0 0 0 0 1 0 0 1 0 0 0 0 0;
                 0 0 1 0 1 0 1 0 0 0 1 0 1 1 0 0 0 0 0 0;
                 0 0 1 0 1 0 1 0 0 0 1 0 1 0 0 0 0 0 0 0;
                 0 0 0 1 0 0 1 0 0 1 1 1 0 0 0 0 0 0 0 0;
                 0 0 0 0 0 0 1 0 0 0 1 0 0 0 0 0 0 0 0 0;
                 0 0 0 0 0 0 0 1 1 1 0 0 0 0 0 0 0 0 0 0];
            [j, k] = size(B);
            app.M(5:5+j-1, 50:50+k-1) = B;
            app.M = [zeros(15,120);
                     app.M;
                     zeros(15,120)];
            app.oldM = app.M;
            set(app.hImg, 'CData', app.M);
        end
        
        function UpdateBinaryAdder(app)
            M2 = app.M(16:36,1:120);
            for i = 2:6:44
                if(M2(7,i+1)==2)
                    M2(2:5 , i:i+2) = app.one;
                else
                    M2(2:5 , i:i+2) = app.zero;
                end
            
                if(M2(13,i+1)==2)
                    M2(15:18 , i:i+2) = app.one;
                else
                    M2(15:18 , i:i+2) = app.zero;
                end     
            end

            for i = 75:6:117
                    if(M2(8,i+1)==2)
                        M2(10:13 , i:i+2) = app.one;
                    else
                        M2(10:13 , i:i+2) = app.zero;
                    end
            end
            app.M(16:36,1:120) = M2;
        end

        function UpdateGrid(app)
            app.nbGenerations = app.nbGenerations+1;
            app.GenerationEditField.Value = app.nbGenerations;
            newM = app.M;
            neighbors = [-1, -1; -1, 0; -1, 1; 0, -1; 0, 1; 1, -1; 1, 0; 1, 1];

            for row = 1:app.m
                for col = 1:app.n
                    switch app.M(row, col)
                        case 0
                            newM(row, col) = 0;
                        case 1
                            electronHeadCount = 0;
                            for k = 1:size(neighbors, 1)
                                newRow = row + neighbors(k, 1);
                                newCol = col + neighbors(k, 2);
                                if newRow >= 1 && newRow <= app.m && newCol >= 1 && newCol <= app.n
                                    if app.M(newRow, newCol) == 2
                                        electronHeadCount = electronHeadCount + 1;
                                    end
                                end
                            end
                            if electronHeadCount == 1 || electronHeadCount == 2
                                newM(row, col) = 2;
                            else
                                newM(row, col) = 1;
                            end
                        case 2
                            newM(row, col) = 3;
                        case 3
                            newM(row, col) = 1;
                    end
                end
            end

            app.M = newM;
            
            if(strcmp(app.DropDown.Value,'Binary Adder'))
                app.UpdateBinaryAdder();
            end

            set(app.hImg, 'CData', app.M)

            if(app.nbGenerations==74 && strcmp(app.DropDown.Value,'Binary Adder'))
                stop(app.updateTimer);
            end
        end

        function ClickCallback(app, ~, ~)
            coords = app.UIAxes.CurrentPoint;
            x = round(coords(1, 1));
            y = round(coords(1, 2));
        
            if x >= 1 && x <= size(app.M, 2) && y >= 1 && y <= size(app.M, 1)
                app.M(y, x) = mod(app.M(y, x) + 1, 4);

                if(strcmp(app.DropDown.Value,'Binary Adder'))
                     app.UpdateBinaryAdder();
                end

                set(app.hImg, 'CData', app.M);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.nbGenerations = 0;
        app.M = zeros(30,60);
        app.DrawGridLines(30,60);
        set(app.hImg, 'CData', app.M);
        app.updateTimer = timer('ExecutionMode', 'fixedRate','Period', 0.1,'TimerFcn', @(~, ~) app.UpdateGrid());
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if(strcmp(app.updateTimer.Running,'off'))
                start(app.updateTimer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if(strcmp(app.updateTimer.Running, 'on'))
                stop(app.updateTimer);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if(strcmp(app.updateTimer.Running,'on'))
                stop(app.updateTimer);
            end
            value = app.DropDown.Value;
            app.nbGenerations = 0;
            app.GenerationEditField.Value = 0;
            if strcmp(value, 'Binary Adder')
                app.M = zeros(50, 120); 
                app.DrawGridLines(50, 120);
            else    
                app.M = zeros(30,60);
                app.DrawGridLines(30, 60);
            end

            switch value
                case 'Sandbox'
                    app.sandbox();
                case 'Clock'
                    app.clock();
                case 'OR'
                    app.or();
                case 'XOR'
                    app.xor();
                case 'Binary Adder'
                    app.binaryAdder();
                case 'AND'
                    app.and();
                case 'Diode'
                    app.diode();
                case 'NOT'
                    app.not();
                case 'Flip Flop'
                    app.flipflop();
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if(strcmp(app.updateTimer.Running, 'on'))  
                stop(app.updateTimer);
            end
            app.nbGenerations = 0;
            app.GenerationEditField.Value =0;
            app.M = app.oldM;
            set(app.hImg, 'CData', app.M);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'Sandbox', 'Clock', 'Diode', 'NOT', 'OR', 'AND', 'XOR', 'Flip Flop', 'Binary Adder'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Placeholder = 'Select Function';
            app.DropDown.Value = 'Sandbox';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GenerationLabel = uilabel(app.GridLayout);
            app.GenerationLabel.Text = 'Generation:';
            app.GenerationEditField = uieditfield(app.GridLayout, 'numeric');
            app.GenerationEditField.Editable = 'off';
            app.WIREWORLDLabel = uilabel(app.GridLayout);
            app.WIREWORLDLabel.Text = 'WIREWORLD';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wireworld

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end